classdef naveen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        SliderLabel     matlab.ui.control.Label
        Slider          matlab.ui.control.Slider
        EditFieldLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.NumericEditField
        UITable         matlab.ui.control.Table
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        im % Description
        perimImage
        %threshold
    end
    
    methods (Access = private)
        
        function perim = showPerim(app)
            thresh = app.Slider.Value;
            %bw = bwperim(app.im > thresh);
            bw = app.im > thresh;
            im = imoverlay(imadjust(app.im),bw,'red');
            %im = imadjust(app.im);
            imshow(im,'parent',app.UIAxes);
            app.UITable.Data = [thresh sum(bw(:))];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inFilename)
            app.im = imread(inFilename);
            imshow(app.im,'parent',app.UIAxes,'DisplayRange',[]);
            
            [min(app.im(:)),max(app.im(:))]
            app.Slider.Limits = double([min(app.im(:)),max(app.im(:))]);
            app.Slider.Value = double(median(app.im(:)));
            app.showPerim();
            %app.threshold = median(app.im(:))
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.EditField.Value = app.Slider.Value;
            app.showPerim();
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.Slider.Value = app.EditField.Value;
            app.showPerim();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = naveen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end